classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Change2       matlab.ui.control.Button
        DateLabel     matlab.ui.control.Label
        TimeLabel     matlab.ui.control.Label
        Filled        matlab.ui.control.Image
        Pour          matlab.ui.control.Image
        ChangeButton  matlab.ui.control.Button
        YesButton     matlab.ui.control.Button
        Button        matlab.ui.control.Button
        InsertMoney   matlab.ui.control.Label
        Welcome       matlab.ui.control.Label
        half_km       matlab.ui.control.Image
        one_km        matlab.ui.control.Image
        two_km        matlab.ui.control.Image
        five_km       matlab.ui.control.Image
        Label         matlab.ui.control.Label
        BalanceLabel  matlab.ui.control.Label
        KMLabel_6     matlab.ui.control.Label
        KMLabel_5     matlab.ui.control.Label
        KMLabel_4     matlab.ui.control.Label
        KMLabel_3     matlab.ui.control.Label
        KMLabel_2     matlab.ui.control.Label
        KMLabel       matlab.ui.control.Label
        CoffeM        matlab.ui.control.Image
        AmericanoI    matlab.ui.control.Image
        RistrettoI    matlab.ui.control.Image
        MacchiatoI    matlab.ui.control.Image
        LatteI        matlab.ui.control.Image
        CappuccinoI   matlab.ui.control.Image
        EspressoI     matlab.ui.control.Image
    end

    
    properties (Access = private)

        Balance=0; % Available money throughout the service
        TotalMoney=42.5; % Total money through all orders
        S_Balance=0; % Starting balance/ All money that user entered
        A_Money=0; % Money earned from ongoing order

        Halves=5; % All half KM coins
        Ones=5; % All one KM coins
        Twos=5; % All two KM coins
        Fives=5; % All five KM coins

        Milk=3; % In Liters
        Water=20; % In Liters
        Sugar= 3; % In Kilograms 

    end
    
    
    methods (Access = private)
        
        % Function for enabling image as 'off' for given time
        function BlockImg(app, imageName, duration)

            app.(imageName).Enable = 'off';
            timerObj = timer('TimerFcn', @(~,~) enableImage(app, imageName), 'StartDelay', duration);
            start(timerObj);

        end


        % Function for enabling image back 'on'
        function enableImage(app, imageName)

            app.(imageName).Enable = 'on';

        end


        % Function for Blocking interface/ setting all the images on screen
        % as 'off' for given time
        function BlockInt(app,duration)

            BlockImg(app, 'half_km', duration); 
            BlockImg(app, 'one_km', duration);  
            BlockImg(app, 'two_km', duration);
            BlockImg(app, 'five_km', duration);

            BlockImg(app, 'EspressoI', duration); 
            BlockImg(app, 'CappuccinoI', duration);
            BlockImg(app, 'LatteI', duration);
            BlockImg(app, 'MacchiatoI', duration);  
            BlockImg(app, 'RistrettoI', duration);
            BlockImg(app, 'AmericanoI', duration); 

        end


        % Function for Blocking interface/ setting all the images on screen
        % as 'off'
        function BlockWhile(app)

            app.('half_km').Enable='off'; 
            app.('one_km').Enable='off'; 
            app.('two_km').Enable='off';
            app.('five_km').Enable='off'; 

            app.('EspressoI').Enable='off'; 
            app.('CappuccinoI').Enable='off';
            app.('LatteI').Enable='off';
            app.('MacchiatoI').Enable='off'; 
            app.('RistrettoI').Enable='off'; 
            app.('AmericanoI').Enable='off';

        end


        % Function for unblocking interface /setting all the images on
        % screen again as 'on'
        function UnBlock(app)

            app.('half_km').Enable='on'; 
            app.('one_km').Enable='on'; 
            app.('two_km').Enable='on';
            app.('five_km').Enable='on'; 
            app.('EspressoI').Enable='on'; 
            app.('CappuccinoI').Enable='on'; 
            app.('LatteI').Enable='on';
            app.('MacchiatoI').Enable='on'; 
            app.('RistrettoI').Enable='on'; 
            app.('AmericanoI').Enable='on';

        end


        % Function for getting current date and time for clock
        function updateTimeAndDate(app)

        % Get the current time
        currentTime = datetime('now', 'Format', 'HH:mm:ss');

        % Get the current date
        currentDate = datestr(now, 'dddd, mmmm dd, yyyy');

        % Update the time and date labels
        app.TimeLabel.Text = [char(currentTime)];
        app.DateLabel.Text = currentDate;

        end

    end              


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            updateTimeAndDate(app);
            % Create a timer to update the time and date every second
            timerObj = timer('TimerFcn', @(~,~) updateTimeAndDate(app), 'Period', 1, 'ExecutionMode', 'fixedRate');
            start(timerObj);

            app.Balance = 0; % Set initial balance
            app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
            app.Button.Visible = 'off';
            app.Pour.Visible = 'off';
            app.Filled.Visible = 'off';

            BlockInt(app,6);
            pause(1);
            
            % Playing 'hello' and 'insert money' audios
            [audioData, Fs] = audioread('hello.mp3'); 
            sound(audioData, Fs);
            pause(3)
            [audioData, Fs] = audioread('insert.mp3'); 
            sound(audioData, Fs);
            pause(2);

            % Load properties from previous order
            if exist('app_properties.mat', 'file')

                loadedProperties = load('app_properties.mat');
                % Assigning loaded properties to properties used in app
                app.Balance = loadedProperties.Balance;
                app.TotalMoney = loadedProperties.TotalMoney;
                app.Halves = loadedProperties.Halves;
                app.Ones = loadedProperties.Ones;
                app.Twos = loadedProperties.Twos;
                app.Fives = loadedProperties.Fives;
                app.Milk = loadedProperties.Milk;
                app.Water = loadedProperties.Water;
                app.Sugar = loadedProperties.Sugar;

            end

        end

        % Image clicked function: EspressoI
        function EspressoIClicked(app, event)

            if app.Balance >= 2.00 % Check if there is enough balance for coffee
                choice = questdlg('Are you sure you want this coffee?', ...
                          'Coffee Confirmation', 'Yes', 'No', 'No');

                switch choice

                    case 'Yes'
                        % Check if there are enough ingridients to make
                        % coffee
                        if app.Water>=0.15 

                            app.Balance = app.Balance - 2.00; % Deduct the coffee price from balance
                            app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')]; % Display updated balance
                            app.Welcome.Text='';
                            app.InsertMoney.Text='';
                            app.Pour.Visible='on';
                            BlockWhile(app); %  Block all functions while coffee is being made
                            app.Label.Text = 'Coffee is brewing...';
                            

                            % Play audio of coffee being made
                            [audioData, Fs] = audioread('coffeesound.mp3');
                            start_index = round(27 * Fs) + 1;
                            end_index = round(31 ...
                                * Fs);
                            audioSection = audioData(start_index:end_index, :);
                            sound(audioSection, Fs);
                            % Simulate 5 seconds for coffee preparation
                            pause(5);
                            clear sound;

                            % PLay sound to indicate that coffee is done
                            [audioData, Fs] = audioread('done.wav'); 
                            sound(audioData, Fs);
                            sound(audioData, Fs);
                            

                            app.Pour.Visible='off';
                            app.Filled.Visible='on';
                            app.Label.Text='';
                            app.InsertMoney.Text = 'Your coffee is done! Enjoy!';
                            app.Button.Visible = 'on';
                            app.Button.Text='Get coffee';
                            app.Water=app.Water-0.15;
                            BlockWhile(app);


                        else
                            app.Welcome.Text='Coffe machine is out of ingridients! ';
                            app.InsertMoney.Text='Call this number 387/61 - 111 - 111';
                            app.Change2.Visible='on';
                        end

                    case 'No'
                        % Play sound and display text for order being
                        % cancelled
                        app.Welcome.Text='';
                        app.InsertMoney.Text=('Order cancelled!');
                        [audioData, Fs] = audioread('cancelled.mp3');
                        BlockInt(app,1);
                        sound(audioData, Fs);
                        pause(1);
                        app.InsertMoney.Text='Choose coffee you want';
                end

            else
                % If there is not enoguh balance to make coffee, display a
                % message and sound
                app.Label.Text = 'Not enough balance for this coffee.';

                [audioData, Fs] = audioread('notenough.mp3');
                sound(audioData, Fs);
                BlockInt(app,2.2);
                pause(2.2);
                app.Label.Text = 'Add Money!'; 

            end

        end

        % Image clicked function: CappuccinoI
        function CappuccinoIImageClicked(app, event)

            if app.Balance >= 3.00 % Check if there is enoguh balance for coffee
                choice = questdlg('Are you sure you want this coffee?', ...
                          'Coffee Confirmation', 'Yes', 'No', 'No');

                switch choice

                    case 'Yes'
                        % Check if there are enough ingridients to make
                        % coffee
                        if app.Milk>=0.15 && app.Water>=0.15 && app.Sugar>=0.015

                        app.Balance = app.Balance - 3.00; % Deduct the coffee price
                        app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
                        app.Welcome.Text='';
                        app.InsertMoney.Text='';               
                        app.Pour.Visible='on';
                        BlockWhile(app); % Block all function while coffee is being made
                        app.Label.Text = 'Coffee is brewing...';


                        % Play audio of coffee being made
                        [audioData, Fs] = audioread('coffeesound.mp3');
                        start_index = round(27 * Fs) + 1;
                        end_index = round(31 ...
                            * Fs);
                        audioSection = audioData(start_index:end_index, :);
                        sound(audioSection, Fs);
                        % Simulate 5 seconds for coffee preparation
                        pause(5);
                        clear sound;

                        % PLay sound to indicate that coffee is done
                        [audioData, Fs] = audioread('done.wav'); 
                        sound(audioData, Fs);
                        sound(audioData, Fs);
                       

                        app.Pour.Visible='off';
                        app.Filled.Visible='on';
                        app.Label.Text='';
                        app.InsertMoney.Text = 'Your coffee is done! Enjoy!';
                        app.Button.Visible = 'on';
                        app.Button.Text='Get coffee';
                        app.Milk=app.Milk-0.15;
                        app.Water=app.Water-0.15;
                        app.Sugar=app.Sugar-0.015;
                        BlockWhile(app);
                        
                        else
                            app.Welcome.Text='Coffe machine is out of ingridients! ';
                            app.InsertMoney.Text='Call this number 387/61 - 111 - 111';
                            app.Change2.Visible='on';
                        end
                    
                    case 'No'
                        % Play sound and display text for order being
                        % cancelled
                        app.Welcome.Text='';
                        app.InsertMoney.Text=('Order cancelled!');
                        [audioData, Fs] = audioread('cancelled.mp3');
                        BlockInt(app,1);
                        sound(audioData, Fs);
                        pause(1);
                        app.InsertMoney.Text='Choose coffee you want';

                end

            else
                % If there is not enoguh balance to make coffee, display a
                % message and sound
                app.Label.Text = 'Not enough balance for this coffee.';
                
                [audioData, Fs] = audioread('notenough.mp3');
                sound(audioData, Fs);
                BlockInt(app,2.2);
                pause(2.2);
                app.Label.Text = 'Add Money!'; 

            end

        end

        % Image clicked function: LatteI
        function LatteIImageClicked(app, event)

            if app.Balance >= 3.00  % Check if there is enoguh balance for coffee
                choice = questdlg('Are you sure you want this coffee?', ...
                          'Coffee Confirmation', 'Yes', 'No', 'No');

                switch choice

                    case 'Yes'
                        % Check if there are enough ingridients to make
                        % coffee
                        if app.Milk>=0.20 && app.Water>=0.15 && app.Sugar>=0.020

                            app.Balance = app.Balance - 3.00; % Deduct the coffee price
                            app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
                            app.Welcome.Text='';
                            app.InsertMoney.Text='';              
                            app.Pour.Visible='on';
                            BlockWhile(app); % Block all function while coffee is being made
                            app.Label.Text = 'Coffee is brewing...';
                            
                            
                            % Play audio of coffee being made
                            [audioData, Fs] = audioread('coffeesound.mp3');
                            start_index = round(27 * Fs) + 1;
                            end_index = round(31 ...
                                * Fs);
                            audioSection = audioData(start_index:end_index, :);
                            sound(audioSection, Fs);
                            % Simulate 5 seconds for coffee preparation
                            pause(5);
                            clear sound;

                            % PLay sound to indicate that coffee is done
                            [audioData, Fs] = audioread('done.wav'); 
                            sound(audioData, Fs);
                            sound(audioData, Fs);
                            
                            
                            app.Pour.Visible='off';
                            app.Filled.Visible='on';
                            app.Label.Text='';
                            app.InsertMoney.Text = 'Your coffee is done! Enjoy!';
                            app.Button.Visible = 'on';
                            app.Button.Text='Get coffee';
                            app.Milk=app.Milk-0.20;
                            app.Water=app.Water-0.15;
                            app.Sugar=app.Sugar-0.020;
                            BlockWhile(app);

                        else
                            app.Welcome.Text='Coffe machine is out of ingridients! ';
                            app.InsertMoney.Text='Call this number 387/61 - 111 - 111'; 
                            app.Change2.Visible='on';

                        end
                    
                    case 'No'

                        % Play sound and display text for order being
                        % cancelled
                        app.Welcome.Text='';
                        app.InsertMoney.Text=('Order cancelled!');
                        [audioData, Fs] = audioread('cancelled.mp3');
                        BlockInt(app,1);
                        sound(audioData, Fs);
                        pause(1);
                        app.InsertMoney.Text='Choose coffee you want';

                end

            else
                % If there is not enoguh balance to make coffee, display a
                % message and sound
                app.Label.Text = 'Not enough balance for this coffee.';
                
                [audioData, Fs] = audioread('notenough.mp3');
                sound(audioData, Fs);
                BlockInt(app,2.2);
                pause(2.2);
                app.Label.Text = 'Add Money!';

            end

        end

        % Image clicked function: MacchiatoI
        function MacchiatoIImageClicked(app, event)

            if app.Balance >= 3.00 % Check if there is enoguh balance for coffee
                choice = questdlg('Are you sure you want this coffee?', ...
                          'Coffee Confirmation', 'Yes', 'No', 'No');

                switch choice

                    case 'Yes'
                        % Check if there are enough ingridients to make
                        % coffee
                        if app.Milk>=0.15 && app.Water>=0.15 && app.Sugar>=0.015

                            app.Balance = app.Balance - 3.00; % Deduct the coffee price
                            app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
                            app.Welcome.Text='';
                            app.InsertMoney.Text='';                
                            app.Pour.Visible='on';
                            BlockWhile(app); % Block all function while coffee is being made                
                            app.Label.Text = 'Coffee is brewing...';
         
                
                            % Play audio of coffee being made
                            [audioData, Fs] = audioread('coffeesound.mp3');
                            start_index = round(27 * Fs) + 1;
                            end_index = round(31 ...
                                * Fs);
                            audioSection = audioData(start_index:end_index, :);
                            sound(audioSection, Fs);
                            % Simulate 5 seconds for coffee preparation
                            pause(5);
                            clear sound;

                            % PLay sound to indicate that coffee is done
                            [audioData, Fs] = audioread('done.wav'); 
                            sound(audioData, Fs);
                            sound(audioData, Fs);


                            app.Pour.Visible='off';
                            app.Filled.Visible='on';
                            app.Label.Text='';
                            app.InsertMoney.Text = 'Your coffee is done! Enjoy!';
                            app.Button.Visible = 'on';
                            app.Button.Text='Get coffee';
                            app.Milk=app.Milk-0.15;
                            app.Water=app.Water-0.15;
                            app.Sugar=app.Sugar-0.015;
                            BlockWhile(app);
                        
                        else
                            app.Welcome.Text='Coffe machine is out of ingridients! ';
                            app.InsertMoney.Text='Call this number 387/61 - 111 - 111';
                            app.Change2.Visible='on';
                        end

                    case 'No'
                        % Play sound and display text for order being
                        % cancelled
                        app.Welcome.Text='';
                        app.InsertMoney.Text=('Order cancelled!');
                        [audioData, Fs] = audioread('cancelled.mp3');
                        BlockInt(app,1);
                        sound(audioData, Fs);
                        pause(1);
                        app.InsertMoney.Text='Choose coffee you want';
                end

            else
                % If there is not enoguh balance to make coffee, display a
                % message and sound
                app.Label.Text = 'Not enough balance for this coffee.';
    
                [audioData, Fs] = audioread('notenough.mp3');
                sound(audioData, Fs);
                BlockInt(app,2.2);
                pause(2.2);
                app.Label.Text = 'Add Money!'; 

            end

        end

        % Image clicked function: RistrettoI
        function RistrettoIImageClicked(app, event)

            if app.Balance >= 2.00 % Check if the balance is sufficient for coffee
                choice = questdlg('Are you sure you want this coffee?', ...
                          'Coffee Confirmation', 'Yes', 'No', 'No');

                switch choice

                    case 'Yes'
                        % Check if there are enough ingridients to make
                        % coffee
                        if app.Water>=0.25 && app.Sugar>=0.015

                            app.Balance = app.Balance - 2.00; % Deduct the coffee price
                            app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
                            app.Welcome.Text='';
                            app.InsertMoney.Text='';              
                            app.Pour.Visible='on';
                            BlockWhile(app); % Block all function while coffee is being made           
                            app.Label.Text = 'Coffee is brewing...';
         
                            % Play audio of coffee being made
                            [audioData, Fs] = audioread('coffeesound.mp3');
                            start_index = round(27 * Fs) + 1;
                            end_index = round(31 ...
                                * Fs);
                            audioSection = audioData(start_index:end_index, :);
                            sound(audioSection, Fs);
                            % Simulate 5 seconds for coffee preparation
                            pause(5);
                            clear sound;

                            % PLay sound to indicate that coffee is done
                            [audioData, Fs] = audioread('done.wav'); 
                            sound(audioData, Fs);
                            sound(audioData, Fs);


                            app.Pour.Visible='off';
                            app.Filled.Visible='on';
                            app.Label.Text='';
                            app.InsertMoney.Text = 'Your coffee is done! Enjoy!';
                            app.Button.Visible = 'on';
                            app.Button.Text='Get coffee';
                            app.Water=app.Water-0.25;
                            app.Sugar=app.Sugar-0.015;
                            BlockWhile(app);
                        
                        else

                            app.Welcome.Text='Coffe machine is out of ingridients! ';
                            app.InsertMoney.Text='Call this number 387/61 - 111 - 111';
                            app.Change2.Visible='on';

                        end
                    
                    case 'No'
                        % Play sound and display text for order being
                        % cancelled
                        app.Welcome.Text='';
                        app.InsertMoney.Text=('Order cancelled!');
                        [audioData, Fs] = audioread('cancelled.mp3');
                        BlockInt(app,1);
                        sound(audioData, Fs);
                        pause(1);
                        app.InsertMoney.Text='Choose coffee you want';
                end
            
            else
                % If there is not enoguh balance to make coffee, display a
                % message and sound
                app.Label.Text = 'Not enough balance for this coffee.';
    
                [audioData, Fs] = audioread('notenough.mp3');
                sound(audioData, Fs);
                BlockInt(app,2.2);
                pause(2.2);
                app.Label.Text = 'Add Money!'; 

            end

        end

        % Image clicked function: AmericanoI
        function AmericanoIImageClicked(app, event)

            if app.Balance >= 2.50 % Check if the balance is sufficient for coffee
                choice = questdlg('Are you sure you want this coffee?', ...
                          'Coffee Confirmation', 'Yes', 'No', 'No');

                switch choice

                    case 'Yes'
                        if app.Water>=0.25 && app.Sugar>=0.005
                            app.Balance = app.Balance - 2.50; % Deduct the coffee price
                            app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
                            app.Welcome.Text='';
                            app.InsertMoney.Text='';               
                            app.Pour.Visible='on';
                            BlockWhile(app);
                            % Show message
                            app.Label.Text = 'Coffee is brewing...';
         
                
                            % Play audio of coffee being made
                            [audioData, Fs] = audioread('coffeesound.mp3');
                            start_index = round(27 * Fs) + 1;
                            end_index = round(31 ...
                                * Fs);
                            audioSection = audioData(start_index:end_index, :);
                            sound(audioSection, Fs);
                            % Simulate 5 seconds for coffee preparation
                            pause(5);
                            clear sound;

                            % PLay sound to indicate that coffee is done
                            [audioData, Fs] = audioread('done.wav'); 
                            sound(audioData, Fs);
                            sound(audioData, Fs);

                            app.Pour.Visible='off';
                            app.Filled.Visible='on';
                            app.Label.Text='';
                            app.InsertMoney.Text = 'Your coffee is done! Enjoy!';
                            app.Button.Visible = 'on';
                            app.Button.Text='Get coffee';
                            app.Water=app.Water-0.25;
                            app.Sugar=app.Sugar-0.005;
                            BlockWhile(app);
                        
                        else
                            app.Welcome.Text='Coffe machine is out of ingridients! ';
                            app.InsertMoney.Text='Call this number 387/61 - 111 - 111';
                            app.Change2.Visible='on';
                        end
                    
                    case 'No'

                        % Play sound and display text for order being
                        % cancelled
                        app.Welcome.Text='';
                        app.InsertMoney.Text=('Order cancelled!');
                        [audioData, Fs] = audioread('cancelled.mp3');
                        BlockInt(app,1);
                        sound(audioData, Fs);
                        pause(1);
                        app.InsertMoney.Text='Choose coffee you want';

                end
            
            else
                
                % If there is not enoguh balance to make coffee, display a
                % message and sound
                app.Label.Text = 'Not enough balance for this coffee.';

                [audioData, Fs] = audioread('notenough.mp3');
                sound(audioData, Fs);
                BlockInt(app,2.2);
                pause(2.2);
                app.Label.Text = 'Add Money!'; 

            end

        end

        % Image clicked function: half_km
        function half_kmClicked(app, event)
        
            % Enables user to enter money that will put balance over 10KM
            if app.Balance>9.5 
                app.('half_km').Enable='off';


            % Adding money to balance 
            else
                if event.Source == app.half_km

                    % Hide the labels
                    app.Welcome.Text = '';
                    app.InsertMoney.Text='';
                    app.Label.Text = '';

                end
                
                % Add money to balance
                app.Balance = app.Balance + 0.50;
                app.S_Balance=app.S_Balance + 0.50;

                % Increasing the amount of half coins, to keep track of
                % every coin that is entered
                app.Halves=app.Halves+1;
    
                % Show balance on display
                app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
    
                % Play audio that simulates coin dropping
                [audioData, Fs] = audioread('coindrop.mp3');
                startSecond = 1;
                startIndex = startSecond * Fs + 1;
                audioToPlay = audioData(startIndex:end, :);
                sound(audioToPlay, Fs);
         
            end

        end

        % Image clicked function: one_km
        function one_kmClicked(app, event)
        
            % Enables user to enter money that will put balance over 10KM
            if app.Balance>9
                app.('one_km').Enable='off';


            % Adding money to balance
            else
                if event.Source == app.one_km

                    % Hide the labels
                    app.Welcome.Text = '';
                    app.InsertMoney.Text='';
                    app.Label.Text = '';

                end

                % Add money to balance
                app.Balance = app.Balance + 1.00;
                app.S_Balance=app.S_Balance + 1.00;

                % Increasing the amount of half coins, to keep track of
                % every coin that is entered
                app.Ones=app.Ones+1; 

                % Show balance on display
                app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
    
                % Play audio that simulates coin dropping
                [audioData, Fs] = audioread('coindrop.mp3');
                startSecond = 1;
                startIndex = startSecond * Fs + 1;
                audioToPlay = audioData(startIndex:end, :);
                sound(audioToPlay, Fs); 

            end

        end

        % Image clicked function: two_km
        function two_kmClicked(app, event)
       
            % Enables user to enter money that will put balance over 10KM
            if app.Balance>8
                app.('two_km').Enable='off';


            % Adding money to balance   
            else
                if event.Source == app.two_km

                    % Hide the labels 
                    app.Welcome.Text = '';
                    app.InsertMoney.Text='';
                   app.Label.Text = '';

                end

                % Add money to balance
                app.Balance = app.Balance + 2.00;
                app.S_Balance=app.S_Balance + 2.00;

                % Increasing the amount of half coins, to keep track of
                % every coin that is entered
                app.Twos=app.Twos+1; 

                % Show balance on display
                app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
    
                % Play audio that simulates coin dropping
                [audioData, Fs] = audioread('coindrop.mp3');
                startSecond = 1;
                startIndex = startSecond * Fs + 1;
                audioToPlay = audioData(startIndex:end, :);
                sound(audioToPlay, Fs);

            end

        end

        % Image clicked function: five_km
        function five_kmClicked(app, event)
        
            % Enables user to enter money that will put balance over 10KM
            if app.Balance>5
                app.('five_km').Enable='off';

            
            % Add money to balance
            else
                if event.Source == app.five_km

                    % Hide the labels
                    app.Welcome.Text = '';
                    app.InsertMoney.Text='';
                    app.Label.Text = '';

                end

                % Add money to balance
                app.Balance = app.Balance + 5.00;
                app.S_Balance=app.S_Balance + 5.00;

                % Increasing the amount of half coins, to keep track of
                % every coin that is entered
                app.Fives=app.Fives+1;

                % Show balance on display
                app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
    
                % Play audio that simulates coin dropping
                [audioData, Fs] = audioread('coindrop.mp3');   
                startSecond = 1;
                startIndex = startSecond * Fs + 1;
                audioToPlay = audioData(startIndex:end, :);
                sound(audioToPlay, Fs);
            
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            % This simulates getting your coffee
            app.Filled.Visible='off';
            app.InsertMoney.Text='';
            app.Button.Visible='off';
            app.YesButton.Visible='on';
            app.ChangeButton.Visible='on';
            app.YesButton.Text='Yes';
            app.ChangeButton.Text='Change';
            app.Welcome.Text='You up for another coffee?';
            

        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)

            % When you click on this button you are able to choose and make
            % another coffee
            app.Welcome.Text='Choose coffee you want!';
            app.YesButton.Visible='off';
            app.ChangeButton.Visible='off';
            UnBlock(app);

        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)

            % Button for returning money back to the user
            app.A_Money=app.S_Balance-app.Balance; % Starting Balance - Ending Balance
            app.TotalMoney=app.TotalMoney+app.A_Money; % Total Money earned
            

            % Algorithm for returning money back to costumer
            % Alogorithams starts first with bigger coins and goes to
            % smaller ones, while change is not equal to 0, when change get
            % equal to 0 while loop stops
            while true % While money is not 0

                % If amount of change is bigger or equal to 5KM and there 
                % is 'five' coins available in machine
                if app.Balance >= 5 && app.Fives > 1 
                    app.Balance = app.Balance - 5;
                    app.Fives = app.Fives - 1;
                

                % If amount of change is bigger or equal to 2KM and there 
                % is 'two' coins  available in machine
                elseif app.Balance >= 2 && app.Twos > 1
                    app.Balance = app.Balance - 2;
                    app.Twos = app.Twos - 1;


                % If amount of change is bigger than or equal to 1KM and
                % there is 'one' coins available in machine
                elseif app.Balance >= 1 && app.Ones > 1
                    app.Balance = app.Balance - 1;
                    app.Ones = app.Ones - 1;


                % If amount of change is bigger or equal to 0.5KM and there
                % is 'half' coins available in machine
                elseif app.Balance >= 0.5 && app.Halves > 1
                    app.Balance = app.Balance - 0.5;
                    app.Halves = app.Halves - 1;


                else
                    break; % Exit the loop if none of the conditions are satisfied
                end
                

                if app.Balance == 0
                    break; % Exit the loop when app.Balance becomes zero
                end

            end
            
            app.Balance = 0;

            % Putting all the important information about the order into a 
            % txt. file
            fileID = fopen('money_data.txt', 'a');
            currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            fprintf(fileID, 'Date and Time: %s\n', char(currentDateTime));
            fprintf(fileID, 'Earned Money: %.2f KM\n', app.A_Money);
            fprintf(fileID, 'Fives Balance: %d\n', app.Fives);
            fprintf(fileID, 'Twos Balance: %d\n', app.Twos);
            fprintf(fileID, 'Ones Balance: %d\n', app.Ones);
            fprintf(fileID, 'Halves Balance: %d\n', app.Halves);
            fprintf(fileID, 'Total Money: %.2f\n\n\n\n', app.TotalMoney);
            fclose(fileID);
            

            % Saving properties that need to be used again when other order 
            % is started
            propertiesToSave.Balance = app.Balance;
            propertiesToSave.TotalMoney = app.TotalMoney;
            propertiesToSave.Halves = app.Halves;
            propertiesToSave.Ones = app.Ones;
            propertiesToSave.Twos = app.Twos;
            propertiesToSave.Fives = app.Fives;
            propertiesToSave.Milk = app.Milk;
            propertiesToSave.Water = app.Water;
            propertiesToSave.Sugar = app.Sugar;
            
            % Save the properties to a MAT-file
            save('app_properties.mat', '-struct', 'propertiesToSave');

            % Displaying balance as zero and goodbye message
            app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
            app.ChangeButton.Visible = 'off';
            app.YesButton.Visible = 'off';
            app.InsertMoney.Text = 'Enjoy your coffee!';
            app.Welcome.Text = 'Goodbye!';

            % PLay sound that simulates giving back change money
            [audioData, Fs] = audioread('change.mp3'); 
            sound(audioData, Fs);

        end

        % Button pushed function: Change2
        function Change2ButtonPushed(app, event)
                
            % Button for giving back money to user when the machine dosen't 
            % have enough ingredients for making coffee
            % Same algorithm for change as for previous button
            while true

                if app.Balance >= 5 && app.Fives > 1
                    app.Balance = app.Balance - 5;
                    app.Fives = app.Fives - 1;

                elseif app.Balance >= 2 && app.Twos > 1
                    app.Balance = app.Balance - 2;
                    app.Twos = app.Twos - 1;

                elseif app.Balance >= 1 && app.Ones > 1
                    app.Balance = app.Balance - 1;
                    app.Ones = app.Ones - 1;

                elseif app.Balance >= 0.5 && app.Halves > 1
                    app.Balance = app.Balance - 0.5;
                    app.Halves = app.Halves - 1;

                else
                    break; % Exit the loop if none of the conditions are satisfied
                end
                
                if app.Balance == 0
                    break; % Exit the loop when app.Balance becomes zero
                end
            end
            
            app.Balance = 0;
            app.S_Balance=0;
            app.BalanceLabel.Text = [num2str(app.Balance, '%.2f KM')];
            app.Change2.Visible = 'off';
            app.YesButton.Visible = 'off';

            % Displaying sorry message
            app.InsertMoney.Text = 'Try again later.';
            app.Welcome.Text = 'Sorry!';

            % PLay audio that simulates giving back money
            [audioData, Fs] = audioread('change.mp3'); 
            sound(audioData, Fs);

        end

        % Callback function
        function ChangeImageClicked(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EspressoI = uiimage(app.UIFigure);
            app.EspressoI.ImageClickedFcn = createCallbackFcn(app, @EspressoIClicked, true);
            app.EspressoI.ImageSource = fullfile(pathToMLAPP, 'espresso.png');
            app.CappuccinoI = uiimage(app.UIFigure);
            app.CappuccinoI.ImageClickedFcn = createCallbackFcn(app, @CappuccinoIImageClicked, true);
            app.CappuccinoI.ImageSource = fullfile(pathToMLAPP, 'cappuccion.png');
            app.LatteI = uiimage(app.UIFigure);
            app.LatteI.ImageClickedFcn = createCallbackFcn(app, @LatteIImageClicked, true);
            app.LatteI.ImageSource = fullfile(pathToMLAPP, 'latte.png');
            app.MacchiatoI = uiimage(app.UIFigure);
            app.MacchiatoI.ImageClickedFcn = createCallbackFcn(app, @MacchiatoIImageClicked, true);
            app.MacchiatoI.ImageSource = fullfile(pathToMLAPP, 'macchiato.png');
            app.RistrettoI = uiimage(app.UIFigure);
            app.RistrettoI.ImageClickedFcn = createCallbackFcn(app, @RistrettoIImageClicked, true);
            app.RistrettoI.ImageSource = fullfile(pathToMLAPP, 'ristretto.png');
            app.AmericanoI = uiimage(app.UIFigure);
            app.AmericanoI.ImageClickedFcn = createCallbackFcn(app, @AmericanoIImageClicked, true);
            app.AmericanoI.ImageSource = fullfile(pathToMLAPP, 'americano.png');
            app.CoffeM = uiimage(app.UIFigure);
            app.CoffeM.ImageSource = fullfile(pathToMLAPP, 'backgroun.jpg');
            app.KMLabel = uilabel(app.UIFigure);
            app.KMLabel.Text = '2.00KM';
            app.KMLabel_2 = uilabel(app.UIFigure);
            app.KMLabel_2.Text = '3.00KM';
            app.KMLabel_3 = uilabel(app.UIFigure);
            app.KMLabel_3.Text = '3.00KM';
            app.KMLabel_4 = uilabel(app.UIFigure);
            app.KMLabel_4.Text = '3.00KM';
            app.KMLabel_5 = uilabel(app.UIFigure);
            app.KMLabel_5.Text = '2.00KM';
            app.KMLabel_6 = uilabel(app.UIFigure);
            app.KMLabel_6.Text = '2.50KM';
            app.BalanceLabel = uilabel(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.five_km = uiimage(app.UIFigure);
            app.five_km.ImageClickedFcn = createCallbackFcn(app, @five_kmClicked, true);
            app.five_km.ImageSource = fullfile(pathToMLAPP, 'five.jpg');
            app.two_km = uiimage(app.UIFigure);
            app.two_km.ImageClickedFcn = createCallbackFcn(app, @two_kmClicked, true);
            app.two_km.ImageSource = fullfile(pathToMLAPP, 'two.jpg');
            app.one_km = uiimage(app.UIFigure);
            app.one_km.ImageClickedFcn = createCallbackFcn(app, @one_kmClicked, true);
            app.one_km.ImageSource = fullfile(pathToMLAPP, 'one.jpg');
            app.half_km = uiimage(app.UIFigure);
            app.half_km.ImageClickedFcn = createCallbackFcn(app, @half_kmClicked, true);
            app.half_km.ImageSource = fullfile(pathToMLAPP, 'fifty.jpg');
            app.Welcome = uilabel(app.UIFigure);
            app.Welcome.Text = 'Welcome! Ready for a coffee?';
            app.InsertMoney = uilabel(app.UIFigure);
            app.InsertMoney.Text = 'Insert money';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Visible = 'off';
            app.YesButton.Text = 'YesButton';
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Visible = 'off';
            app.ChangeButton.Text = 'ChangeButton';
            app.Pour = uiimage(app.UIFigure);
            app.Pour.Visible = 'off';
            app.Pour.ImageSource = fullfile(pathToMLAPP, 'Pour.png');
            app.Filled = uiimage(app.UIFigure);
            app.Filled.Visible = 'off';
            app.Filled.ImageSource = fullfile(pathToMLAPP, 'filled.png');
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Label2';
            app.DateLabel = uilabel(app.UIFigure);
            app.DateLabel.Text = 'Label3';
            app.Change2 = uibutton(app.UIFigure, 'push');
            app.Change2.ButtonPushedFcn = createCallbackFcn(app, @Change2ButtonPushed, true);
            app.Change2.Visible = 'off';
            app.Change2.Text = 'Change';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end